classdef CiftiStorm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        NewprocessMenu             matlab.ui.container.Menu
        DatasetMenu                matlab.ui.container.Menu
        DatasetLoadMenu            matlab.ui.container.Menu
        DatasetListMenu            matlab.ui.container.Menu
        DatasetDeleteMenu          matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        ToolsMenu                  matlab.ui.container.Menu
        StructuralprocessingMenu   matlab.ui.container.Menu
        StandardMenu               matlab.ui.container.Menu
        PreFreesurferMenu          matlab.ui.container.Menu
        FreesurferMenu_2           matlab.ui.container.Menu
        postFreesurferMenu         matlab.ui.container.Menu
        LegacyMenu                 matlab.ui.container.Menu
        FreesurferMenu             matlab.ui.container.Menu
        CiftifyMenu                matlab.ui.container.Menu
        FSLBetMenu                 matlab.ui.container.Menu
        ForwadandSourcemodelsMenu  matlab.ui.container.Menu
        MEEGprepMenu               matlab.ui.container.Menu
        BCVARETAMenu               matlab.ui.container.Menu
        ViewMenu                   matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        UpdateMenu                 matlab.ui.container.Menu
        UsermanualMenu             matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        Toolbar                    matlab.ui.container.Toolbar
        PushToolNewProcess         matlab.ui.container.toolbar.PushTool
        PushToolDefault_Params     matlab.ui.container.toolbar.PushTool
        PushToolRunProcess         matlab.ui.container.toolbar.PushTool
        PushToolListDataset        matlab.ui.container.toolbar.PushTool
        PushToolExplorerDS         matlab.ui.container.toolbar.PushTool
        GridLayout                 matlab.ui.container.GridLayout
        PanelControls              matlab.ui.container.Panel
        LabelBreadcrumbs           matlab.ui.control.Label
        PanelViewer                matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        TabExplorer                matlab.ui.container.Tab
        TreeInput                  matlab.ui.container.Tree
        ProtocolsTab               matlab.ui.container.Tab
        ProtocolsDropDown          matlab.ui.control.DropDown
        ProtocolsDropDownLabel     matlab.ui.control.Label
        TreeProtocol               matlab.ui.container.Tree
        TabDatasets                matlab.ui.container.Tab
        DatasetsDropDown           matlab.ui.control.DropDown
        DatasetsDropDownLabel      matlab.ui.control.Label
        TreeDatasets               matlab.ui.container.Tree
    end


    properties (Access = private)
        cfs_properties % Description
        ciftistormDir % Description
        bst_file % Description
        Datasets % Description
        currentDT % Description
        currentPr % Description
        currentAxes % Description
        colorMap % Description
        currentSub % Description
    end

    methods (Access = private)
        % Explore the Tree Folder
        function ExploreFolder(app,nodeData)
            app.TreeInput.Children.delete;
            if(ismember(nodeData.name,{'..'}))
                rootDir = fileparts(nodeData.folder);
                dirName = strsplit(rootDir,filesep);
                dirName = dirName{end};
            else
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
            end
            app.Breadcrumbs(rootDir)
            parent = uitreenode(app.TreeInput,'Text',dirName);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(rootDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    % Contex menu
                    cm = uicontextmenu(app.UIFigure);
                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end
        % Create the Breadcrumbs in the navigation tree
        function Breadcrumbs(app,path)
            pathParts = strsplit(path,filesep);
            text = strcat(filesep, " ");
            for i=2:length(pathParts)
                if(i < length(pathParts))
                    text = strcat(text, pathParts{i}," ",filesep," ");
                else
                    text = strcat(text, pathParts{i});
                end
            end
            app.LabelBreadcrumbs.Text = strcat("<font style='color:blue;'>",text,"</font>");
            app.LabelBreadcrumbs.Interpreter = 'html';
        end
        % Fill a TreeNode with subfolders
        function FillFolder(app,node)
            nodeData = node.NodeData;
            if(~ismember(nodeData.name,{'..'}) && ~ismember(nodeData.name,{'.'}))
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
                subfile_list = dir(rootDir);
                subfile_list(ismember({subfile_list.name},{'.','..'})) = [];
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/folder.png';
                            subnode.NodeData = subfile_list(j);
                            sub_cm = uicontextmenu(app.UIFigure);
                            m1 = uimenu(sub_cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                            m2 = uimenu(sub_cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                            subnode.ContextMenu = sub_cm;
                        end
                    end
                end
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(~subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/file.jpeg';
                            subnode.NodeData = subfile_list(j);
                        end
                    end
                end
            end
        end   

        function PlotSurf(app,surfaces)
            delete(app.PanelViewer.Children);
            template = load('axes.mat');
            app.currentAxes = template.axes;
            alpha = 1;
            for i=1:length(surfaces)
                surf = surfaces(i);
                if(surf.Selected)
                    surface = surf.Data;
                    if(isequal(surf.Type,'cortex'))
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [], ...
                            'FaceColor',        [255/255 204/255 204/255], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'cortex');
                            alpha = alpha - 0.60;
                    end
                    if(isequal(surf.Type,'innerskull'))
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [],...
                            'FaceColor',        [0.7 0.7 0.7], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'innerskull');
                        alpha = alpha - 0.10;
                    end
                    if(isequal(surf.Type,'outerskull'))
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [], ...
                            'FaceColor',        [0.7 0.7 0.7], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'outerskull');
                         alpha = alpha - 0.10;
                    end
                    if(isequal(surf.Type,'scalp'))
                        patch( app.currentAxes,...
                            'Faces',            surface.Faces, ...
                            'Vertices',         surface.Vertices,...
                            'VertexNormals',    surface.VertNormals, ...
                            'VertexNormalsMode', 'auto',...
                            'FaceNormals',      [],...
                            'FaceNormalsMode',  'auto',...
                            'FaceVertexCData',  [], ...
                            'FaceColor',        [0.7 0.7 0.7], ...
                            'FaceAlpha',        alpha, ...
                            'AlphaDataMapping', 'none', ...
                            'EdgeColor',        'none', ...
                            'EdgeAlpha',        alpha, ...
                            'LineWidth',         0.5,...
                            'LineJoin',         'miter',...
                            'AmbientStrength',  0.5, ...
                            'DiffuseStrength',  0.5, ...
                            'SpecularStrength', 0.2, ...
                            'SpecularExponent', 1, ...
                            'SpecularColorReflectance', 0.5, ...
                            'FaceLighting',     'gouraud', ...
                            'EdgeLighting',     'gouraud', ...
                            'Tag',              'scalp');
                         alpha = alpha - 0.10;
                    end
                end
            end
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,0,0);
        end
        
        function PlotChannelsPosition(app, Cdata, Sscalp, update)
            Channel     = Cdata.Channel;
            ChanLoc     = [Channel.Loc]';
            if(update)
                delete(app.currentAxes.Children(1));
                ctColor         = [.9,.9,0]; % Yellow
                nVert           = 34;
                dSize           = 5;
                ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
                patch(app.currentAxes,...
                    'Faces',               ElectrodeGrid.Faces, ...
                    'Vertices',            ElectrodeGrid.Vertices,...
                    'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                    'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                    'FaceColor',           'flat', ...
                    'FaceAlpha',           'flat', ...
                    'AlphaDataMapping',    'none', ...
                    ElectrodeGrid.Options{:});
            else
                delete(app.currentAxes);
                template = load('axes.mat');
                app.currentAxes = template.axes;
                patch(app.currentAxes, ...
                    'Faces',Sscalp.Faces, ...
                    'Vertices',Sscalp.Vertices, ...
                    'FaceVertexCData',[], ...
                    'FaceColor',[.6 .6 .6], ...
                    'EdgeColor','none', ...
                    'AlphaDataMapping', 'none', ...
                    'EdgeColor',        'none', ...
                    'EdgeAlpha',        1, ...
                    'BackfaceLighting', 'lit', ...
                    'AmbientStrength',  0.5, ...
                    'DiffuseStrength',  0.5, ...
                    'SpecularStrength', 0.2, ...
                    'SpecularExponent', 1, ...
                    'SpecularColorReflectance', 0.5, ...
                    'FaceLighting',     'gouraud', ...
                    'EdgeLighting',     'gouraud', ...
                    'FaceAlpha',.99, ...
                    'Tag', 'Scalp');
                ctColor         = [.9,.9,0]; % Yellow
                nVert           = 34;
                dSize           = 5;
                ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
                patch(app.currentAxes,...
                    'Faces',               ElectrodeGrid.Faces, ...
                    'Vertices',            ElectrodeGrid.Vertices,...
                    'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                    'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                    'FaceColor',           'flat', ...
                    'FaceAlpha',           'flat', ...
                    'AlphaDataMapping',    'none', ...
                    ElectrodeGrid.Options{:});
                app.currentAxes.Colormap = app.colorMap.cmap_a;
                set(app.currentAxes,"Parent",app.PanelViewer);
                rotate3d(app.currentAxes,'on');
                view(app.currentAxes,90,0);

            end
        end
        
        function PlotHeadmodel(app, Headmodel, Cortex, Cdata, Sscalp)
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);
            template = load('axes.mat');
            app.currentAxes = template.axes;
            elecName = app.PanelControls.Children(1).Value;

            Channel     = Cdata.Channel;
            elecIndex   = find(ismember({Channel.Name},elecName),1);
            %% initial computations
            %% plot Lead Field
            [Ne,Nv]     = size(Headmodel.Gain);
            Nv          = Nv/3;
            XYZ         = Cortex.Vertices;
            Kn          = reshape(Headmodel.Gain,Ne,3,Nv);
            Kn          = permute(Kn,[1,3,2]);
            Kq          = permute(Kn, [1 3 2]);
            Kq          = permute(Kq, [3 2 1]);
            Kqm         = sqrt(dot(Kq,Kq,2));
            KqmC        = Kqm(:,:,elecIndex);
            quiver3(app.currentAxes,XYZ(:,1),XYZ(:,2),XYZ(:,3), ...
                Kq(:,1,elecIndex),Kq(:,2,elecIndex),Kq(:,3,elecIndex), ...
                'LineWidth',2,'Color',[0 0 1],'AutoScaleFactor',5);
            

            %% define electrode to show and Marker size
            patch( app.currentAxes,...
                'Faces',            Cortex.Faces, ...
                'Vertices',         Cortex.Vertices,...
                'VertexNormals',    Cortex.VertNormals, ...
                'VertexNormalsMode', 'auto',...
                'FaceNormals',      [],...
                'FaceNormalsMode',  'auto',...
                'FaceVertexCData',  KqmC, ...
                'FaceColor',        'inter', ...
                'FaceAlpha',        1, ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'LineWidth',         0.5,...
                'LineJoin',         'miter',...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'Tag',              'AnatSurface');
            

            % Ploting channles
            ChanLoc         = [Channel.Loc]';
            ctColor         = [.9,.9,0]; % Yellow
            nVert = 34;
            dSize = 4;
            ElectrodeGrid = CreateGeometry3DElectrode_2(ChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'flat', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});

            % Ploting selected channel
            SelectChannel         = Cdata.Channel(elecIndex);
            SelectChanLoc         = [SelectChannel.Loc]';
            ctColor         = [1 0 0];
            ElectrodeGrid = CreateGeometry3DElectrode_2(SelectChanLoc,'EEG',Sscalp, ctColor, nVert, dSize);
            patch(app.currentAxes,...
                'Faces',               ElectrodeGrid.Faces, ...
                'Vertices',            ElectrodeGrid.Vertices,...
                'FaceVertexCData',     ElectrodeGrid.FaceVertexCData, ...
                'FaceVertexAlphaData', ElectrodeGrid.FaceVertexAlphaData, ...
                'FaceColor',           'flat', ...
                'FaceAlpha',           'flat', ...
                'AlphaDataMapping',    'none', ...
                ElectrodeGrid.Options{:});
            app.currentAxes.Colormap = app.colorMap.cmap_a;
            set(app.currentAxes,"Parent",app.PanelViewer);
            rotate3d(app.currentAxes,'on');
            view(app.currentAxes,180,90);
            axis(app.currentAxes,'equal');
            axis(app.currentAxes,'off');
        end
        
        function ChannelsCorrection(app, cbt, val)
            options = cbt.Parent.Children;
            optionSelected = '';
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton') && option.Value)
                    optionSelected = option;
                    break;
                end
            end 
            if(isequal(optionSelected,''))
                d = uiprogressdlg(app.UIFigure,'Title','Waiting for message box');
                msg = msgbox('Please select an option for the Channels correction.','Info'); 
                waitfor(msg);
                close(d);
                figure(app.UIFigure);
                return;
            end            
            Cdata = app.currentSub.Transformation.Cdata;
            FinalTransf = app.currentSub.Transformation.FinalTransf;
            [Rnew, Tnew, Rescale]  = ComputeChannelsTransf(optionSelected.Tag,val);
            SensorsVertices = [Cdata.Channel.Loc]';
            isChanged = 1:length(Cdata.Channel);
            [FinalTransf, SensorsVertices, isChanged] = ApplyChannelsTransf(FinalTransf, SensorsVertices, isChanged, Rnew, Tnew, Rescale);
            app.currentSub.Transformation.isChanged = isChanged;
            app.currentSub.Transformation.FinalTransf = FinalTransf;
            for i=1:length(app.currentSub.Transformation.Cdata.Channel)
                app.currentSub.Transformation.Cdata.Channel(i).Loc(:) = SensorsVertices(i,:);
            end
            app.PlotChannelsPosition(app.currentSub.Transformation.Cdata, app.currentSub.Surfaces(4).Data, true);
        end
        
        function SelectChannelOption(app, cbt)
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(~isequal(cbt.Tag,option.Tag) && isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end            
        end
        
        function SaveChannelsCorrections(app, cbt)
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end
            if(app.currentSub.Transformation.isChanged)
                d = uiprogressdlg(app.UIFigure,'Title','Waiting for message box');
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Cancel';
                % Use the TeX interpreter to format the question
                quest = {strcat("You have maked channel transformations"), ...
                    'Would you like save this changes?'};
                answer = questdlg(quest,'Apply changes',...
                    'Apply','Cancel','Discard',opts);
                % Handle response
                switch answer
                    case 'Apply'
                        Cdata = app.currentSub.Cdata.Data;
                        transfSensors = [app.currentSub.Transformation.Cdata.Channel.Loc]';
                        for i=1:length(Cdata.Channel)
                            Cdata.Channel(i).Loc(:) = transfSensors(i,:);
                        end
                        save(app.currentSub.Cdata.FileName,'-struct','Cdata');
                        app.currentSub.Transformation.isChanged = false;
                        app.currentSub.Transformation.FinalTransf = eye(4);
                        app.currentSub.Cdata.Data = Cdata;
                        app.MarkSubjectToRecompute();
                        msg = msgbox('The Channel transformations was successfully saved.','Info');
                        waitfor(msg);
                    case 'Cancel'
                        close(d);
                        figure(app.UIFigure);
                        return;
                    case 'Discard'
                        app.currentSub.Transformation.Cdata = app.currentSub.Cdata.Data;
                        app.currentSub.Transformation.isChanged = false;
                        app.currentSub.Transformation.FinalTransf = eye(4);
                end
                close(d);
                figure(app.UIFigure);
                app.PlotChannelsPosition(app.currentSub.Cdata.Data,app.currentSub.Surfaces(4).Data, false);
            end
            
        end
        
        function ResetChannelsCorrections(app, cbt)  
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end  
            Sscalp = load(app.currentSub.Surfaces(4).FileName);
            Cdata = load(app.currentSub.Cdata.FileName);
            app.currentSub.Transformation.Cdata = Cdata;
            app.currentSub.Transformation.isChanged = false;
            app.currentSub.Transformation.FinalTransf = eye(4);
            app.PlotChannelsPosition(Cdata,Sscalp,false);
        end
        
        function ChannelsControls(app)
             % Channels controls
             if(isequal(class(app.PanelViewer.Children(1)),'matlab.ui.container.Panel'))
                delete(app.PanelViewer.Children(1))
             end
            y = app.PanelViewer.Position(4) - 130;
            pn = uipanel(Title="Channels correction options",Position=[10 y 210 98], Parent=app.PanelViewer, FontWeight="bold");
            labelX = uilabel(Text="X",Position=[5 48 22 30],Parent=pn,FontSize=14, FontWeight="bold");
            RotX = uibutton('state',Text="",Icon="guide/images/rotation.png",Position=[20 52 22 22],Parent=pn,Tag="RotX", ...
                Tooltip="Rotation on X",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            TransX = uibutton('state',Text="",Icon="guide/images/translation.png",Position=[45 52 22 22],Parent=pn,Tag="TransX", ...
                Tooltip="Translation on X",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            ResizeX = uibutton('state',Text="",Icon="guide/images/resize.png",Position=[70 52 22 22],Parent=pn,Tag="ResizeX", ...
                Tooltip="Resize on X",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            labelY = uilabel(Text="Y",Position=[5 24 30 30],Parent=pn,FontSize=14, FontWeight="bold");
            RotY = uibutton('state',Text="", Icon="guide/images/rotation.png",Position=[20 27 22 22],Parent=pn,Tag="RotY", ...
                Tooltip="Rotation on Y",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            TransY = uibutton('state',Text="", Icon="guide/images/translation.png",Position=[45 27 22 22],Parent=pn,Tag="TransY", ...
                Tooltip="Translation on Y",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            ResizeY = uibutton('state',Text="", Icon="guide/images/resize.png",Position=[70 27 22 22],Parent=pn,Tag="ResizeY", ...
                Tooltip="Resize on Y",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            labelZ = uilabel(Text="Z",Position=[5 0 22 30],Parent=pn,FontSize=14, FontWeight="bold");
            RotZ = uibutton('state',Text="", Icon="guide/images/rotation.png",Position=[20 3 22 22],Parent=pn,Tag="RotZ", ...
                Tooltip="Rotation on Z",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            TransZ = uibutton('state',Text="", Icon="guide/images/translation.png",Position=[45 3 22 22],Parent=pn,Tag="TransZ", ...
                Tooltip="Translation on Z",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));
            ResizeZ = uibutton('state',Text="", Icon="guide/images/resize.png",Position=[70 3 22 22],Parent=pn,Tag="ResizeZ", ...
                Tooltip="Resize on Z",ValueChangedFcn=@(cbt,event) app.SelectChannelOption(cbt));

            % selectSensor = uibutton(Text="", Icon="guide/images/selectSensor.png",Position=[120 50 25 25],Parent=pn,Tag="sensor", ...
            %     Tooltip="Project sensors to Scalp", ButtonPushedFcn=@(cbt,event) app.PojectSensorsToScalp());
            projectSensors = uibutton(Text="", Icon="guide/images/project.png",Position=[127 50 25 25],Parent=pn,Tag="project", ...
                Tooltip="Project sensors to Scalp", ButtonPushedFcn=@(cbt,event) app.ChannelProjectToScalp(cbt));
            reset = uibutton(Text="", Icon="guide/images/reset.png",Position=[155 50 25 25],Parent=pn,Tag="reset", ...
                Tooltip="Reset corrections", ButtonPushedFcn=@(cbt,event) app.ResetChannelsCorrections(cbt));
            save = uibutton(Text="", Icon="guide/images/OK.png",Position=[183 50 25 25],Parent=pn,Tag="save", ...
                Tooltip="Save corrections", ButtonPushedFcn=@(cbt,event) app.SaveChannelsCorrections(cbt));

            left = uibutton(Text="", Icon="guide/images/left.png",Position=[140 10 25 25],Parent=pn, ...
                ButtonPushedFcn=@(cbt,event) app.ChannelsCorrection(cbt, -0.001),Tag="left");
            right = uibutton(Text="", Icon="guide/images/right.png",Position=[170 10 25 25],Parent=pn, ...
                ButtonPushedFcn=@(cbt,event) app.ChannelsCorrection(cbt, 0.001),Tag="right");
        end
        
        function ChannelProjectToScalp(app, cbt)
            options = cbt.Parent.Children;
            for i=1:length(options)
                option = options(i);
                if(isequal(class(option),'matlab.ui.control.StateButton'))
                    option.Value = false;
                end
            end
            if(app.currentSub.Transformation.isChanged)
                Cdata = app.currentSub.Transformation.Cdata;
                Sscalp = app.currentSub.Surfaces(4).Data;

                % Project electrodes on the surface
                ChanLocProj = channel_project_scalp(Sscalp.Vertices, [Cdata.Channel.Loc]');
                for i=1:length(app.currentSub.Transformation.Cdata.Channel)
                    app.currentSub.Transformation.Cdata.Channel(i).Loc(:) = ChanLocProj(i,:);
                end
                app.currentSub.Transformation.isChanged = true;
                app.currentSub.Transformation.FinalTransf = eye(4);
                app.PlotChannelsPosition(app.currentSub.Transformation.Cdata,Sscalp,true);
            end
        end
        
        function MarkSubjectToRecompute(app)
            
        end
    end

    methods (Access = public)

        function DefineProperties(app,event,action,clasif,nodeData)
            switch clasif
                case 'Output'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    app.cfs_properties.general_params.output_path = base_path;
                    saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Anatomy'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc_restore.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc_restore.nii.gz'))...
                                && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc.nii.gz')))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msgbox({'The selected folder do not contain any Ciftify structure',...
                            'Please select a correct anatomy folder.'},'Info');
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders are not a HCP structure.',...
                            'Please check the Anatomy folder or you can continue in this way.'},'Info');
                    end
                    app.cfs_properties.anatomy_params.anatomy_type.type_list{2}.base_path = base_path;
                    saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Non-brain'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                                || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                                || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msgbox({'The selected folder do not contain any FSL Bet structure',...
                            'Please select a correct anatomy folder.'},'Info');
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders are not a FSL Bet structure.',...
                            'Please check the FSL non-brain surfaces folder or you can continue in this way.'},'Info');
                    end
                    app.cfs_properties.anatomy_params.common_params.non_brain_surfaces.base_path = base_path;
                    saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");            
            app.cfs_properties = get_properties();

            % Checking dependencies
            BST_path = app.cfs_properties.general_params.bst_config.bst_path;            
            if(~cfs_check_properties('BST_path',BST_path))
                d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Set';
                % Use the TeX interpreter to format the question
                quest = {strcat("The Brainstorm path is not defined"), ...
                    'Please define it before continue'};
                answer = questdlg(quest,'BST path',...
                    'Set','No',opts);
                % Handle response
                switch answer
                    case 'Set'
                        try
                            while true
                                folder = uigetdir("Select the Brinstorm path");
                                if(isfile(fullfile(folder,'brainstorm.m')))
                                    app.cfs_properties.general_params.bst_config.bst_path = folder;
                                    % saving property files
                                    saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
                                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
                                    h.smartIndentContents
                                    h.save
                                    h.close
                                    msg = msgbox({'The Brainstorm path was successfully defined'},'Info');
                                    waitfor(msg);
                                    delete(d);
                                    figure(app.UIFigure);
                                    break;
                                else                                    
                                    opts.Interpreter = 'tex';
                                    % Include the desired Default answer
                                    opts.Default = 'Yes';
                                    % Use the TeX interpreter to format the question
                                    quest = {strcat("The selected folder do not contain the BrainStorm toolbox."), ...
                                        'Would you like to define a correct Brainstorm path?'};
                                    answer = questdlg(quest,'BST path',...
                                        'Yes','Cancel',opts);
                                    switch answer
                                        case 'Yes'
                                            
                                        case 'Cancel'
                                            msgbox({'The process will be stoped.', ...
                                                ' Please select a correct Brainstorm path.'},'Info');
                                            delete(app);
                                            return;
                                    end
                                end
                            end
                        catch
                            msgbox({'The process will be stoped.', ...
                                ' Please select a correct Brainstorm path.'},'Info');
                            delete(app);
                            return;
                        end
                    case 'No'
                        msgbox({'The process will be stoped.', ...
                            ' Please select a correct Brainstorm path.'},'Info');
                        delete(app);
                        return;
                end
            end

            % 
            figure(app.UIFigure);
            userDir = getUserDir();
            app.Breadcrumbs(userDir);
            app.colorMap = load('templates/mycolormap_brain_basic_conn.mat');
            parent = uitreenode(app.TreeInput,'Text',userDir);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(userDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                    end
                    % Context menu
                    cm = uicontextmenu(app.UIFigure);
                    mo = uimenu(cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',fileStruct},'Separator','on');

                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@app.DefineProperties,'Anatomy',fileStruct});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@app.DefineProperties,'Non-brain',fileStruct});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);

            %% Load Protocols
            general_params = app.cfs_properties.general_params;
            if(cfs_check_properties('BST_db',general_params.bst_config.db_path))
                if(isequal(general_params.bst_config.db_path,'local'))
                    bst_db = fullfile(userDir,'.brainstorm');
                else
                    [bst_db,~,~] = fileparts(general_params.bst_config.db_path);
                end
                if(isfile(fullfile(bst_db,'brainstorm.mat')))
                    app.bst_file = fullfile(bst_db,'brainstorm.mat');
                    bst_info = load(app.bst_file);
                    protocols = bst_info.ProtocolsListInfo;
                    app.ProtocolsDropDown.Items = {protocols.Comment};
                    app.ProtocolsDropDown.Items{end+1} = '--Select--';
                    app.ProtocolsDropDown.Value = '--Select--';
                end
            end

            %% Load Datasets
            app.Datasets = cfs_get('datasets');
            if(~isempty(app.Datasets))
                app.DatasetsDropDown.Items = {app.Datasets.Name};
                app.DatasetsDropDown.ItemsData = {app.Datasets.UUID};
                app.DatasetsDropDown.Items{end+1} = '--Select--';
                app.DatasetsDropDown.ItemsData{end+1} = '--Select--';
                app.DatasetsDropDown.Value = '--Select--';
            end

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: NewprocessMenu
        function NewprocessMenuSelected(app, event)
            obj_configure = Params_configuration();
        end

        % Double-clicked callback: TreeInput
        function TreeInputDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                ExploreFolder(app,node.NodeData);
            end
        end

        % Clicked callback: TreeInput
        function TreeInputClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                FillFolder(app,node);
            end
        end

        % Callback function: PushToolRunProcess
        function PushToolRunProcessClicked(app, event)
            app.cfs_properties = get_properties('run');
            [~, reject_subjects]     = check_properties(app.cfs_properties);

            %% BrainStorm configuration
            disp('-->> Preparing BrainStorm properties.');
            disp('==========================================================================');
            bst_path        = app.cfs_properties.general_params.bst_config.bst_path;
            addpath(genpath(bst_path));
            status          = starting_brainstorm(app.cfs_properties);

            %% Calling dataset function to analysis
            if(status)
                process_error = cfs_process_interface(app.cfs_properties,reject_subjects);
                % save("process_output.mat","process_error","reject_subjects");
            end

            %% Stopping BrainStorm
            disp('==========================================================================');
            brainstorm('stop');
            disp('==========================================================================');
            disp(properties.generals.name);
            disp('==========================================================================');
            close all;
            clear all;
        end

        % Callback function: PushToolNewProcess
        function PushToolNewProcessClicked(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');
            obj_configure = Params_configuration();
            waitfor(obj_configure);
            close(d)
            figure(app.UIFigure);
        end

        % Value changed function: ProtocolsDropDown
        function ProtocolsDropDownValueChanged(app, event)
            delete(app.TreeProtocol.Children);
            comment = app.ProtocolsDropDown.Value;
            if(~isequal(comment,'--Select--'))
                bst_info = load(app.bst_file);
                protocols = bst_info.ProtocolsListInfo;
                protocol_info = protocols(find(ismember({protocols.Comment},comment),1));
                app.currentPr = load(fullfile(protocol_info.STUDIES,'protocol.mat'));

                subjects = app.currentPr.ProtocolSubjects.Subject;
                studies = app.currentPr.ProtocolStudies.Study;
                for i=1:length(subjects)
                    subject = subjects(i);                    
                    node = uitreenode(app.TreeProtocol, 'Text', subject.Name);
                    node.NodeData = subject;
                    node.UserData = 'subject';
                    icon_file = 'guide/images/subject.png';
                    node.Icon = icon_file;

                    anat = subject.Anatomy(subject.iAnatomy);
                    if(~isempty(anat))
                        subnode = uitreenode(node, 'Text', 'MRI');
                        subnode.Icon = 'guide/images/MRI.png';
                        subnode.NodeData = anat;
                    end

                    subnode = uitreenode(node, 'Text', 'BEM');
                    subnode.Icon = 'guide/images/scalp.png';
                    subnode.NodeData.Comment = 'BEM';

                    scalp = subject.Surface(subject.iScalp);
                    if(~isempty(scalp))
                        subnode = uitreenode(node, 'Text', 'Scalp');
                        subnode.Icon = 'guide/images/scalp.png';
                        subnode.NodeData = scalp;
                    end

                    outer = subject.Surface(subject.iOuterSkull);
                    if(~isempty(outer))
                        subnode = uitreenode(node, 'Text', 'OuterSkull');
                        subnode.Icon = 'guide/images/outerskull.png';
                        subnode.NodeData = outer;
                    end

                    inner = subject.Surface(subject.iInnerSkull);
                    if(~isempty(inner))
                        subnode = uitreenode(node, 'Text', 'InnerSkull');
                        subnode.Icon = 'guide/images/innerskull.png';
                        subnode.NodeData = inner;
                    end

                    cortex = subject.Surface(subject.iCortex);
                    if(~isempty(cortex))
                        subnode = uitreenode(node, 'Text', 'Cortex');
                        subnode.Icon = 'guide/images/cortex_tree.png';
                        subnode.NodeData = cortex;
                    end

                    sub_studies = fileparts({studies.BrainStormSubject});
                    idStudies = ismember(sub_studies,subject.Name);
                    subStudy = studies(idStudies);
                    idStudy = ~ismember({subStudy.Name},'@default_study');
                    study = subStudy(idStudy);
                    node.NodeData.Study = study;
                    if(~isempty(study.Channel))
                        channel = study.Channel;
                        subnode = uitreenode(node, 'Text', 'Channels');
                        subnode.Icon = 'guide/images/channels.png';
                        subnode.NodeData = channel;
                    end
                    if(~isempty(study.iHeadModel) && ~isempty(study.HeadModel(study.iHeadModel)))
                        headmodel = study.HeadModel(study.iHeadModel);
                        subnode = uitreenode(node, 'Text', 'Headmodel');
                        subnode.Icon = 'guide/images/headmodel.jpeg';
                        subnode.NodeData = headmodel;
                    end
                end
            end
        end

        % Callback function: PushToolDefault_Params
        function PushToolDefault_ParamsClicked(app, event)
            opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Yes';
            % Use the TeX interpreter to format the question
            quest = {strcat("You will restore the default params"), ...
                'Would you like continue?'};
            answer = questdlg(quest,'Apply changes',...
                'Yes','No',opts);
            % Handle response
            switch answer
                case 'Yes'
                    set_default_properties(app);
                case 'No'
            end
        end

        % Menu selected function: MEEGprepMenu
        function MEEGprepMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');            
            meegprep;
            waitfor(obj_configure);
            close(d)
            figure(app.UIFigure);
        end

        % Menu selected function: BCVARETAMenu
        function BCVARETAMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');            
            bcvareta;
            waitfor(obj_configure);
            close(d)
            figure(app.UIFigure);
        end

        % Value changed function: DatasetsDropDown
        function DatasetsDropDownValueChanged(app, event)
            value = event.Value;            
            delete(app.TreeDatasets.Children);
            if(isequal(value,'--Select--'))
                 msgbox('Please select a correct Dataset.','Info');
                        return;
            else
                dt_ind = find(ismember({app.Datasets.UUID},value),1);
                app.currentDT = app.Datasets(dt_ind);
                for i=1:length(app.currentDT.Participants)
                    participant = app.currentDT.Participants(i);
                    node = uitreenode(app.TreeDatasets, 'Text', participant.SubID);
                    node.NodeData = participant;
                    icon_file = 'guide/images/subject.png';
                    node.Icon = icon_file;
                end
            end
        end

        % Menu selected function: DatasetLoadMenu
        function DatasetLoadMenuSelected(app, event)
            homedir = char(java.lang.System.getProperty('user.home'));
            d = uiprogressdlg(app.UIFigure,'Title','Loading Dataset......');
            folder = uigetdir(homedir,"Select the Dataset folder");
            if(isequal(folder,0))
                close(d)
                figure(app.UIFigure);
                return;
            end
            tempDT = jsondecode(fileread(fullfile(folder,'CiftiStorm.json')));
            tempDT.Location = fullfile(folder,'CiftiStorm.json');
            if(~isempty(find(ismember({app.Datasets.UUID},tempDT.UUID),1)))
                 msgbox({'The selected Dataset is already in the CiftiStorm Database',...
                            'Would you like to import any way?.'},'Info');
            else
                app.currentDT = tempDT;
                if(isempty(app.Datasets))
                    app.Datasets = app.currentDT;
                else
                    app.Datasets(end + 1) = app.currentDT;
                end
                [icondata,iconcmap] = imread("guide/images/success.png");
                msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                waitfor(msg);
                disp("-->> Dataset loaded");
                cfs_set('datasets',app.Datasets);

                app.DatasetsDropDown.Items = {app.Datasets.Name};
                app.DatasetsDropDown.ItemsData = {app.Datasets.UUID};
                app.DatasetsDropDown.Items{end+1} = '--Select--';
                app.DatasetsDropDown.ItemsData{end+1} = '--Select--';
                app.DatasetsDropDown.Value = app.currentDT.UUID;

                delete(app.TreeDatasets.Children);
                for i=1:length(app.currentDT.Participants)
                    participant = app.currentDT.Participants(i);
                    node = uitreenode(app.TreeDatasets, 'Text', participant.SubID);
                    node.NodeData = participant;
                    icon_file = 'guide/images/subject.png';
                    node.Icon = icon_file;
                end
            end
            close(d)
            figure(app.UIFigure);
        end

        % Selection changed function: TreeProtocol
        function TreeProtocolSelectionChanged(app, event)
            selectedNode = app.TreeProtocol.SelectedNodes;            
            delete(app.PanelControls.Children);
            nodeData = selectedNode.NodeData;
            bst_info = load(app.bst_file);
            db_dir = bst_info.BrainStormDbDir;
            ProtocolName = app.currentPr.ProtocolInfo.Comment;
            protocolDir = fullfile(db_dir,ProtocolName);
            if(isequal(selectedNode.UserData,'subject'))
                app.currentSub.SubID    = selectedNode.Text;
                app.currentSub.Transformation.isChanged = false;
            else
                app.currentSub.SubID    = selectedNode.Parent.Text;
                app.currentSub.Transformation.isChanged = false;
                subject                 = selectedNode.Parent.NodeData;
                surfaces(1).Type        = 'cortex';
                surfaces(1).Selected    = false;
                surfaces(1).FileName    = '';
                surfaces(2).Type        = 'innerskull';
                surfaces(2).Selected    = false;
                surfaces(2).FileName    = '';
                surfaces(3).Type        = 'outerskull';
                surfaces(3).Selected    = false;
                surfaces(3).FileName    = '';
                surfaces(4).Type        = 'scalp';
                surfaces(4).Selected    = false;
                surfaces(4).FileName    = '';
                app.currentSub.Surfaces = surfaces;
                if(isequal(nodeData.Comment,'MRI'))

                end
                if(isequal(selectedNode.Text,'BEM'))                    
                    app.currentSub.Transformation.isChanged = false;
                    app.currentSub.Surfaces(4).Selected = true;
                    app.currentSub.Surfaces(4).FileName = fullfile(protocolDir,'anat',subject.Surface(subject.iScalp).FileName);
                    app.currentSub.Surfaces(4).Data = load(fullfile(protocolDir,'anat',subject.Surface(subject.iScalp).FileName));
                    app.currentSub.Surfaces(3).Selected = true;
                    app.currentSub.Surfaces(3).FileName = fullfile(protocolDir,'anat',subject.Surface(subject.iOuterSkull).FileName);
                    app.currentSub.Surfaces(3).Data = load(fullfile(protocolDir,'anat',subject.Surface(subject.iOuterSkull).FileName));
                    app.currentSub.Surfaces(2).Selected = true;
                    app.currentSub.Surfaces(2).FileName = fullfile(protocolDir,'anat',subject.Surface(subject.iInnerSkull).FileName);
                    app.currentSub.Surfaces(2).Data = load(fullfile(protocolDir,'anat',subject.Surface(subject.iInnerSkull).FileName));
                    app.currentSub.Surfaces(1).Selected = true;
                    app.currentSub.Surfaces(1).FileName = fullfile(protocolDir,'anat',subject.Surface(subject.iCortex).FileName);
                    app.currentSub.Surfaces(1).Data = load(fullfile(protocolDir,'anat',subject.Surface(subject.iCortex).FileName));
                    app.PlotSurf( app.currentSub.Surfaces);
                end
                if(isequal(selectedNode.Text,'Scalp'))
                    app.currentSub.Surfaces(4).Selected = true;
                    app.currentSub.Surfaces(4).FileName = fullfile(protocolDir,'anat',nodeData.FileName);
                    app.currentSub.Surfaces(4).Data     = load(fullfile(protocolDir,'anat',nodeData.FileName));
                    app.PlotSurf( app.currentSub.Surfaces);
                end
                if(isequal(selectedNode.Text,'OuterSkull'))
                    app.currentSub.Surfaces(3).Selected = true;
                    app.currentSub.Surfaces(3).FileName = fullfile(protocolDir,'anat',nodeData.FileName);
                    app.currentSub.Surfaces(3).Data = load(fullfile(protocolDir,'anat',nodeData.FileName));
                    app.PlotSurf( app.currentSub.Surfaces);
                end
                if(isequal(selectedNode.Text,'InnerSkull'))
                    app.currentSub.Surfaces(2).Selected = true;
                    app.currentSub.Surfaces(2).FileName = fullfile(protocolDir,'anat',nodeData.FileName);
                    app.currentSub.Surfaces(2).Data = load(fullfile(protocolDir,'anat',nodeData.FileName));
                    app.PlotSurf( app.currentSub.Surfaces);
                end
                if(isequal(selectedNode.Text,'Cortex'))
                    app.currentSub.Surfaces(1).Selected = true;
                    app.currentSub.Surfaces(1).FileName = fullfile(protocolDir,'anat',nodeData.FileName);
                    app.currentSub.Surfaces(1).Data = load(fullfile(protocolDir,'anat',nodeData.FileName));
                    app.PlotSurf( app.currentSub.Surfaces);
                end
                if(isequal(selectedNode.Text,'Channels'))
                    Cdata = load(fullfile(protocolDir,'data',nodeData.FileName));
                    app.currentSub.Cdata.Data = Cdata;
                    app.currentSub.Cdata.FileName = fullfile(protocolDir,'data',nodeData.FileName);
                    app.currentSub.Transformation.Cdata = Cdata;
                    app.currentSub.Transformation.FinalTransf = eye(4);
                    Sscalp = load(fullfile(protocolDir,'anat',subject.Surface(subject.iScalp).FileName));
                    app.currentSub.Surfaces(4).FileName = fullfile(protocolDir,'anat',subject.Surface(subject.iScalp).FileName);
                    app.currentSub.Surfaces(4).Data = Sscalp;                                        
                    app.PlotChannelsPosition(Cdata,Sscalp,false);
                    app.ChannelsControls();
                end
                if(isequal(selectedNode.Text,'Headmodel'))
                    headmodel = load(fullfile(protocolDir,'data',nodeData.FileName));
                    cortex = load(fullfile(protocolDir,'anat',subject.Surface(subject.iCortex).FileName));
                    Cdata = load(fullfile(protocolDir,'data',subject.Study.Channel.FileName));
                    Sscalp = load(fullfile(protocolDir,'anat',subject.Surface(subject.iScalp).FileName));
                    pos = app.PanelControls.Position;
                    uilabel(app.PanelControls,'Text','Channels:',...
                        'Position',[(pos(3)/2-60) 4 60 30]);
                    uidropdown(app.PanelControls,"Items",{Cdata.Channel.Name},...
                        'Value', 'Fz','Position',[(pos(3)/2) 4 75 27],...
                        "ValueChangedFcn",@(dropd,event) app.PlotHeadmodel(headmodel, cortex, Cdata, Sscalp));
                    app.PlotHeadmodel(headmodel, cortex, Cdata, Sscalp);
                end 
            end
        end

        % Menu selected function: FreesurferMenu
        function FreesurferMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Checking dependencies......');
            if ismac || isunix
                [status,cmdout] = system('recon-all --version');
                if(isequal(status,0))
                    obj_configure = FreesurferUI();  
                    waitfor(obj_configure);
                else
                    msg = msgbox({'We can not find the freesurfer command on the system.',...
                        'Please install Freesurfer or check the configuration.'},'Warnning','warn');
                    waitfor(msg);
                end
            else
                msg = msgbox({'The structural processing is not supported by your Operating System.'},'Warnning','warn');
                waitfor(msg);
            end
            close(d)
            figure(app.UIFigure);
        end

        % Menu selected function: CiftifyMenu
        function CiftifyMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Checking dependencies......');
            if ismac || isunix
                [status,cmdout] = system('ciftify-recon-all -h');
                if(isequal(status,0))
                    obj_configure = CiftifyUI();
                    waitfor(obj_configure);
                else
                    msg = msgbox({'We can not find the Ciftify command on the system.',...
                        'Please install Ciftify or check the configuration.'},'Warnning','warn');
                    waitfor(msg);
                end
            else
                msg = msgbox({'The structural processing is not supported by your Operating System.'},'Warnning','warn');
                waitfor(msg);
            end
            close(d)
            figure(app.UIFigure);
        end

        % Menu selected function: FSLBetMenu
        function FSLBetMenuSelected(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Checking dependencies......');
            if ismac || isunix
                [status,cmdout] = system('bet2 -h');
                if(isequal(status,0))
                    
                end
            else
                msg = msgbox({'The structural processing is not supported by your Operating System.'},'Warnning','warn');
                waitfor(msg);

            end
            close(d)
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewprocessMenu = uimenu(app.FileMenu);
            app.NewprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprocessMenuSelected, true);
            app.NewprocessMenu.Text = 'New process';
            app.DatasetMenu = uimenu(app.FileMenu);
            app.DatasetMenu.Text = 'Dataset';
            app.DatasetLoadMenu = uimenu(app.DatasetMenu);
            app.DatasetLoadMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetLoadMenuSelected, true);
            app.DatasetLoadMenu.Text = 'Load';
            app.DatasetListMenu = uimenu(app.DatasetMenu);
            app.DatasetListMenu.Text = 'List';
            app.DatasetDeleteMenu = uimenu(app.DatasetMenu);
            app.DatasetDeleteMenu.Text = 'Delete';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.StructuralprocessingMenu = uimenu(app.ToolsMenu);
            app.StructuralprocessingMenu.Text = 'Structural processing';
            app.StandardMenu = uimenu(app.StructuralprocessingMenu);
            app.StandardMenu.Text = 'Standard';
            app.PreFreesurferMenu = uimenu(app.StandardMenu);
            app.PreFreesurferMenu.Text = 'PreFreesurfer';
            app.FreesurferMenu_2 = uimenu(app.StandardMenu);
            app.FreesurferMenu_2.Text = 'Freesurfer';
            app.postFreesurferMenu = uimenu(app.StandardMenu);
            app.postFreesurferMenu.Text = 'postFreesurfer';
            app.LegacyMenu = uimenu(app.StructuralprocessingMenu);
            app.LegacyMenu.Text = 'Legacy';
            app.FreesurferMenu = uimenu(app.LegacyMenu);
            app.FreesurferMenu.MenuSelectedFcn = createCallbackFcn(app, @FreesurferMenuSelected, true);
            app.FreesurferMenu.Text = 'Freesurfer';
            app.CiftifyMenu = uimenu(app.LegacyMenu);
            app.CiftifyMenu.MenuSelectedFcn = createCallbackFcn(app, @CiftifyMenuSelected, true);
            app.CiftifyMenu.Text = 'Ciftify';
            app.FSLBetMenu = uimenu(app.LegacyMenu);
            app.FSLBetMenu.MenuSelectedFcn = createCallbackFcn(app, @FSLBetMenuSelected, true);
            app.FSLBetMenu.Text = 'FSL Bet';
            app.ForwadandSourcemodelsMenu = uimenu(app.ToolsMenu);
            app.ForwadandSourcemodelsMenu.Text = 'Forwad and Source models';
            app.MEEGprepMenu = uimenu(app.ToolsMenu);
            app.MEEGprepMenu.MenuSelectedFcn = createCallbackFcn(app, @MEEGprepMenuSelected, true);
            app.MEEGprepMenu.Text = 'MEEGprep';
            app.BCVARETAMenu = uimenu(app.ToolsMenu);
            app.BCVARETAMenu.MenuSelectedFcn = createCallbackFcn(app, @BCVARETAMenuSelected, true);
            app.BCVARETAMenu.Text = 'BC-VARETA';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UpdateMenu = uimenu(app.HelpMenu);
            app.UpdateMenu.Text = 'Update';
            app.UsermanualMenu = uimenu(app.HelpMenu);
            app.UsermanualMenu.Text = 'User manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushToolNewProcess = uipushtool(app.Toolbar);
            app.PushToolNewProcess.Tooltip = {'New process'};
            app.PushToolNewProcess.ClickedCallback = createCallbackFcn(app, @PushToolNewProcessClicked, true);
            app.PushToolNewProcess.Icon = fullfile(pathToMLAPP, 'images', 'new_process.jpeg');
            app.PushToolDefault_Params = uipushtool(app.Toolbar);
            app.PushToolDefault_Params.Tooltip = {'Set default params'};
            app.PushToolDefault_Params.ClickedCallback = createCallbackFcn(app, @PushToolDefault_ParamsClicked, true);
            app.PushToolDefault_Params.Icon = fullfile(pathToMLAPP, 'images', 'default.png');
            app.PushToolRunProcess = uipushtool(app.Toolbar);
            app.PushToolRunProcess.Tooltip = {'Run process'};
            app.PushToolRunProcess.ClickedCallback = createCallbackFcn(app, @PushToolRunProcessClicked, true);
            app.PushToolRunProcess.Icon = fullfile(pathToMLAPP, '', 'images', 'run.jpg');
            app.PushToolListDataset = uipushtool(app.Toolbar);
            app.PushToolListDataset.Tooltip = {'List Datasets'};
            app.PushToolListDataset.Icon = fullfile(pathToMLAPP, 'images', 'list_dataset.png');
            app.PushToolExplorerDS = uipushtool(app.Toolbar);
            app.PushToolExplorerDS.Tooltip = {'Explore Datasets'};
            app.PushToolExplorerDS.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabExplorer = uitab(app.TabGroup);
            app.TabExplorer.Title = 'Explorer';
            app.TreeInput = uitree(app.TabExplorer);
            app.TreeInput.ClickedFcn = createCallbackFcn(app, @TreeInputClicked, true);
            app.TreeInput.DoubleClickedFcn = createCallbackFcn(app, @TreeInputDoubleClicked, true);
            app.ProtocolsTab = uitab(app.TabGroup);
            app.ProtocolsTab.Title = 'Protocols';
            app.TreeProtocol = uitree(app.ProtocolsTab);
            app.TreeProtocol.SelectionChangedFcn = createCallbackFcn(app, @TreeProtocolSelectionChanged, true);
            app.ProtocolsDropDownLabel = uilabel(app.ProtocolsTab);
            app.ProtocolsDropDownLabel.Text = 'Protocols:';
            app.ProtocolsDropDown = uidropdown(app.ProtocolsTab);
            app.ProtocolsDropDown.Items = {'--Select--'};
            app.ProtocolsDropDown.ValueChangedFcn = createCallbackFcn(app, @ProtocolsDropDownValueChanged, true);
            app.ProtocolsDropDown.Value = '--Select--';
            app.TabDatasets = uitab(app.TabGroup);
            app.TabDatasets.Title = 'Datasets';
            app.TreeDatasets = uitree(app.TabDatasets);
            app.DatasetsDropDownLabel = uilabel(app.TabDatasets);
            app.DatasetsDropDownLabel.Text = 'Datasets:';
            app.DatasetsDropDown = uidropdown(app.TabDatasets);
            app.DatasetsDropDown.Items = {'--Select--'};
            app.DatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetsDropDownValueChanged, true);
            app.DatasetsDropDown.Value = '--Select--';
            app.PanelViewer = uipanel(app.GridLayout);
            app.PanelViewer.Title = 'Viewer';
            app.LabelBreadcrumbs = uilabel(app.GridLayout);
            app.LabelBreadcrumbs.Text = '';
            app.PanelControls = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CiftiStorm

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end