classdef DatasetUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasetsUIFigure  matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        HelpMenu          matlab.ui.container.Menu
        Toolbar           matlab.ui.container.Toolbar
        LoadPushTool      matlab.ui.container.toolbar.PushTool
        ExplorePushTool   matlab.ui.container.toolbar.PushTool
        DeletePushTool    matlab.ui.container.toolbar.PushTool
        PushTool          matlab.ui.container.toolbar.PushTool
        GridLayout        matlab.ui.container.GridLayout
        Panel             matlab.ui.container.Panel
        CloseButton       matlab.ui.control.Button
        UITable           matlab.ui.control.Table
    end

    
    properties (Access = public)
        ciftistormDir;
        Datasets_file;
        Datasets;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.SelectionType = 'row';
            movegui(app.DatasetsUIFigure,[800,200]); 
        end

        % Close request function: DatasetsUIFigure
        function DatasetsUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            uDataset = app.Datasets(indices(indices(1)));
            switch indices(2)
                case 1
                    uDataset.Name = newData;
                case 2
                    uDataset.Description = newData;
            end
            app.Datasets(indices(indices(1))) = uDataset;
            saveJSON(app.Datasets,app.Datasets_file);
            msg = msgbox({'The Dataset was updated successfully!!!'},'Success',"custom",icondata,iconcmap);
            movegui(msg,'north');
        end

        % Callback function: LoadPushTool
        function LoadPushToolClicked(app, event)
            folder = uigetdir("Select the Dataset folder");
            dataset_file = fullfile(folder,'BC_VARETA.mat');
            if(isfile(dataset_file))
                BC_VARETA_info = load(dataset_file);
                BC_VARETA_info.Path = folder;
                BC_VARETA_info.UUID =  java.util.UUID.randomUUID.toString ;
                if(~isempty(app.Datasets) && contains(BC_VARETA_info.Name,{app.Datasets.Name}))
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Yes';
                    % Use the TeX interpreter to format the question
                    quest = {strcat("The dataset: ", BC_VARETA_info.Name, " is already loaded"), ...
                        'Would you like import this the dataset anyway?'};
                    answer = questdlg(quest,'Apply changes',...
                        'Add','Replace','Cancel',opts);
                    % Handle response
                    switch answer
                        case 'Add'
                            app.Datasets(end + 1) = BC_VARETA_info;
                            tableData.Name = {app.Datasets.Name}';
                            tableData.Description = {app.Datasets.Description}';
                            tableData.Path_Location = {app.Datasets.Path}';
                            app.UITable.Data = struct2table(tableData);
                            disp("-->> Dataset loaded");
                            [icondata,iconcmap] = imread("guide/images/success.png");
                            msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                            movegui(msg,'north');
                            saveJSON(app.Datasets,app.Datasets_file);
                        case 'Replace'
                            index = find(contains(BC_VARETA_info.Name,{app.Datasets.Name}),1);
                            app.Datasets(index) = BC_VARETA_info;
                            disp("-->> Dataset replaced");
                            [icondata,iconcmap] = imread("guide/images/success.png");
                            msg = msgbox({'The Dataset was replaced successfully!!!'},'Success',"custom",icondata,iconcmap);
                            movegui(msg,'north');
                            saveJSON(app.Datasets,app.Datasets_file);
                        case 'Cancel'
                    end
                else
                    app.Datasets = BC_VARETA_info;
                    [icondata,iconcmap] = imread("guide/images/success.png");
                    msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                    movegui(msg,'north');
                    disp("-->> Dataset added");
                    saveJSON(app.Datasets,app.Datasets_file);
                end
            else
                msgbox({'The selected folder do not contains the dataset file.',...
                    ' Please select a correct folder.'},'Error',"error","modal");
            end
        end

        % Callback function: DeletePushTool
        function DeletePushToolClicked(app, event)
            row = app.UITable.Selection;
            if(isempty(row))
                msgbox({'Please select a Dataset to delete.'},'Error',"error","modal");
            else
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Yes';
                % Use the TeX interpreter to format the question
                quest = {strcat("Would you like to delete the Dataset: ", app.Datasets(row).Name)};
                answer = questdlg(quest,'Delete',...
                    'Delete','Cancel',opts);
                % Handle response
                switch answer
                    case 'Delete'
                        app.Datasets(row) = [];
                        [icondata,iconcmap] = imread("guide/images/success.png");
                        msg = msgbox({'The Dataset was deleted successfully!!!'},'Success',"custom",icondata,iconcmap);
                        movegui(msg,'north');
                        tableData.Name = {app.Datasets.Name}';
                        for i=1:length(app.Datasets)
                            descrip_array{i}        = string(app.Datasets(i).Description);
                        end
                        tableData.Description       = descrip_array';
                        tableData.Path_Location     = {app.Datasets.Path}';
                        datasetUI.UITable.Data      = struct2table(tableData);
                        disp("-->> Dataset deleted");
                        saveJSON(app.Datasets,app.Datasets_file);
                    case 'Cancel'
                end
            end
        end

        % Callback function: ExplorePushTool
        function ExplorePushToolClicked(app, event)
            row = app.UITable.Selection;
            if(isempty(row))
                msg = msgbox({'Please select a Dataset to delete.'},'Error',"error","modal");
                movegui(msg,'north');
            else
                DSExplore = app.Datasets(row);                
                appExplore = ExploreDS(app,DSExplore);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DatasetsUIFigure = uifigure('Visible', 'off');
            app.DatasetsUIFigure.Name = 'Datasets';
            app.DatasetsUIFigure.CloseRequestFcn = createCallbackFcn(app, @DatasetsUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.DatasetsUIFigure);
            app.FileMenu.Text = 'File';
            app.HelpMenu = uimenu(app.DatasetsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.DatasetsUIFigure);
            app.LoadPushTool = uipushtool(app.Toolbar);
            app.LoadPushTool.Tooltip = {'Load Dataset'};
            app.LoadPushTool.ClickedCallback = createCallbackFcn(app, @LoadPushToolClicked, true);
            app.LoadPushTool.Icon = fullfile(pathToMLAPP, 'images', 'upload.png');
            app.ExplorePushTool = uipushtool(app.Toolbar);
            app.ExplorePushTool.Tooltip = {'Explore Dataset'};
            app.ExplorePushTool.ClickedCallback = createCallbackFcn(app, @ExplorePushToolClicked, true);
            app.ExplorePushTool.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.DeletePushTool = uipushtool(app.Toolbar);
            app.DeletePushTool.Tooltip = {'Delete Dataset'};
            app.DeletePushTool.ClickedCallback = createCallbackFcn(app, @DeletePushToolClicked, true);
            app.DeletePushTool.Icon = fullfile(pathToMLAPP, 'images', 'delete.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Help'};
            app.PushTool.Icon = fullfile(pathToMLAPP, 'images', 'help.png');
            app.GridLayout = uigridlayout(app.DatasetsUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Description'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Panel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.DatasetsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasetsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasetsUIFigure)
        end
    end
end